classdef Perceptron < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SingleLayerDesignerTab        matlab.ui.container.Tab
        InputsDropDownLabel           matlab.ui.control.Label
        InputsDropDown                matlab.ui.control.DropDown
        BiasEditFieldLabel            matlab.ui.control.Label
        BiasEditField                 matlab.ui.control.NumericEditField
        WeightsTable                  matlab.ui.control.Table
        NeuronArchitectureAxes        matlab.ui.control.UIAxes
        GeometricInterpretationAxes   matlab.ui.control.UIAxes
        SingleLayerOnlineLearningTab  matlab.ui.container.Tab
        TrainingDataTable             matlab.ui.control.Table
        OnlineLearningTable           matlab.ui.control.Table
        TrainNeuronButton             matlab.ui.control.Button
        LearningRateEditFieldLabel    matlab.ui.control.Label
        LearningRateEditField         matlab.ui.control.NumericEditField
        NeuronArchitectureAxes2       matlab.ui.control.UIAxes
        MultilayerDesignerTab         matlab.ui.container.Tab
        ParametersTable               matlab.ui.control.Table
        MultilayerArchitectureAxes    matlab.ui.control.UIAxes
        Layer1InterpretationAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        theta    % Description
        radius
        circlex % Description
        circley % Description
        texty
        textbias
        textw
        rcolor
        gcolor
        bcolor
        x
        y
    end
    
    methods (Access = private)
        function drawNeuron(app,axes,withGeometricInterpretation)
            % plot surrounding circle for threshold function
            axes.NextPlot ='replace';
            plot(axes, app.radius*app.circlex,app.radius*app.circley);
            axes.NextPlot='add';
            
            % plot axes for threshold function
            quiver(axes,-app.radius*0.75,0,app.radius*1.5,0,0,'MaxHeadSize',.5,'Color',app.bcolor)
            quiver(axes,0,-app.radius*0.5,0,app.radius*1.35,0,'MaxHeadSize',.5,'Color',app.bcolor)
            
            % plot threshold function
            xs = [-app.radius/2 -eps 0 app.radius/2];
            plot(axes,xs,step(app,xs,0),'LineWidth',2,'Color',app.rcolor);
            
            % plot sum
            app.plotSum(axes)
            
            n = str2double(app.InputsDropDown.Value);
            sgn = '-';
            if app.BiasEditField.Value>=0
                sgn = '+';
            end
            switch(n)
                case 1
                    app.plotInput(axes,1,0);
                    app.plotWeightToSum(axes,0);
                    
                    text(axes,app.radius*2,1,'y=step(w_1*x_1+bias)')
                    app.texty = text(axes,app.radius*2,-1,['=step(' ...
                        num2str(app.WeightsTable.Data.Values(1)) '*x_1' sgn num2str(abs(app.BiasEditField.Value)) ')']);
                    
                    if withGeometricInterpretation
                        cla(app.GeometricInterpretationAxes);
                        app.GeometricInterpretationAxes.NextPlot = 'add';
                        drawGeometricInterpretation1D(app,app.GeometricInterpretationAxes,app.BiasEditField.Value,app.WeightsTable.Data.Values(1));
                    end
                case 2
                    app.plotInput(axes,1,2);
                    app.plotWeightToSum(axes,2);
                    app.plotInput(axes,2,-2);
                    app.plotWeightToSum(axes,-2);
                    
                    text(axes,app.radius*2,1,'y=(w_1*x_1+w_2*x_2+bias)')
                    app.texty = text(axes,app.radius*2,-1,['=step(' ...
                        num2str(app.WeightsTable.Data.Values(1)) '*x_1+' num2str(app.WeightsTable.Data.Values(2)) '*x_2' sgn num2str(abs(app.BiasEditField.Value)) ')']);
                    
                    if withGeometricInterpretation
                        cla(app.GeometricInterpretationAxes);
                        app.GeometricInterpretationAxes.NextPlot = 'add';
                        drawGeometricInterpretation2D(app,app.GeometricInterpretationAxes,app.BiasEditField.Value,app.WeightsTable.Data.Values(1),app.WeightsTable.Data.Values(2),'o','-');
                    end
            end
            quiver(axes,app.radius,0,app.radius,0,0,'MaxHeadSize',.5,'Color',app.bcolor) % line func to output
            axes.XLim = [-app.radius*4,app.radius*6];
            axes.YLim = [-app.radius*n,app.radius*n];
            axes.DataAspectRatio = [1 1 1];
            axes.Visible = 'off';
        end
        
        function drawMultilayer(app)
            app.MultilayerArchitectureAxes.NextPlot ='add';
            % input layers
            text(app.MultilayerArchitectureAxes,-app.radius-1,-app.radius,'x_2','Color',app.bcolor,'FontSize',14)
            quiver(app.MultilayerArchitectureAxes,-app.radius/2-1,-app.radius,app.radius/2,0,0,'MaxHeadSize',.5,'Color',app.bcolor,'LineWidth',2)
            plotNeuron(app,0,-app.radius,[],app.bcolor);
            
            text(app.MultilayerArchitectureAxes,-app.radius-1,app.radius,'x_1','Color',app.bcolor,'FontSize',14)
            quiver(app.MultilayerArchitectureAxes,-app.radius/2-1,app.radius,app.radius/2,0,0,'MaxHeadSize',.5,'Color',app.bcolor,'LineWidth',2)
            plotNeuron(app,0,app.radius,[],app.bcolor);
            
            % hidden layers
            quiver(app.MultilayerArchitectureAxes,1,-app.radius,app.radius*2,0,0,'MaxHeadSize',.5,'Color',app.gcolor,'LineWidth',2,'MaxHeadSize',.5)
            text(app.MultilayerArchitectureAxes,2,-app.radius-0.5,'w_{2,2}','Color',app.gcolor,'FontSize',14)
            plotNeuron(app,2+app.radius*2,-app.radius,'w_{0,2}',app.gcolor);
            
            quiver(app.MultilayerArchitectureAxes,1,-app.radius,app.radius*2,2*app.radius,0,'MaxHeadSize',.5,'Color',app.gcolor,'LineWidth',2)
            text(app.MultilayerArchitectureAxes,2,-app.radius+1,'w_{2,1}','Color',app.gcolor,'FontSize',14)
            
            quiver(app.MultilayerArchitectureAxes,1,app.radius,app.radius*2,-2*app.radius,0,'MaxHeadSize',.5,'Color',app.gcolor,'LineWidth',2)
            text(app.MultilayerArchitectureAxes,2,app.radius-1,'w_{1,2}','Color',app.gcolor,'FontSize',14)
            
            quiver(app.MultilayerArchitectureAxes,1,app.radius,app.radius*2,0,0,'MaxHeadSize',.5,'Color',app.gcolor,'LineWidth',2)
            text(app.MultilayerArchitectureAxes,2,app.radius+0.5,'w_{1,1}','Color',app.gcolor,'FontSize',14)
            plotNeuron(app,2+app.radius*2,app.radius,'w_{0,1}',app.gcolor);
            
            % output layer
            plotNeuron(app,2+app.radius*4,0,['3'],app.rcolor);
            quiver(app.MultilayerArchitectureAxes,3+app.radius*2,app.radius,app.radius,-app.radius,0,'Color',app.rcolor,'LineWidth',2,'MaxHeadSize',.5)
            text(app.MultilayerArchitectureAxes,3+app.radius*2+1,app.radius-.25,'2','Color',app.rcolor,'FontSize',14)
            quiver(app.MultilayerArchitectureAxes,3+app.radius*2,-app.radius,app.radius,+app.radius,0,'Color',app.rcolor,'LineWidth',2,'MaxHeadSize',.5)
            text(app.MultilayerArchitectureAxes,3+app.radius*2+1,-app.radius+.5,'2','Color',app.rcolor,'FontSize',14)
            quiver(app.MultilayerArchitectureAxes,3+app.radius*4,0,app.radius/2,0,0,'Color',app.rcolor,'LineWidth',2,'MaxHeadSize',.5)
            
            app.MultilayerArchitectureAxes.DataAspectRatio = [1 1 1];
            app.MultilayerArchitectureAxes.XLim = [-app.radius*2,app.radius*6];
            app.MultilayerArchitectureAxes.YLim = [-app.radius*2,app.radius*2];
            
            app.MultilayerArchitectureAxes.Visible = 'off';
        end
        
        function plotNeuron(app,xdisplacement,ydisplacement,bias,c)
            plot(app.MultilayerArchitectureAxes,app.circlex+xdisplacement,app.circley+ydisplacement,'LineWidth',2,'Color',c)
            if ~isempty(bias)
                text(app.MultilayerArchitectureAxes,xdisplacement-0.5,ydisplacement,bias,'Color',c,'FontSize',14)
            end
        end
        
        function drawGeometricInterpretation1D(app,axes,bias,w1)
            xintercept = -bias/w1; 
            % add x-axis
            if xintercept>=0
                quiver(axes,-app.radius,0,xintercept+app.radius*2,0,0,'MaxHeadSize',.15,'Color','k')
            else
                quiver(axes,xintercept-app.radius,0,-xintercept+app.radius*2,0,'MaxHeadSize',.15,'Color','k')
            end
            % add y-axis
            quiver(axes,0,-app.radius*1.5,0,app.radius*3,0,'MaxHeadSize',.15,'Color','k')
            % add separation line
            line(axes,[xintercept xintercept], [-app.radius,app.radius],'LineWidth',2,'Color',app.bcolor)
            % add normal and text showing plane where values are 1
            sgnx = sign(w1);sgnx(sgnx==0)=1;
            quiver(axes,xintercept,0,sgnx,0,0,'MaxHeadSize',.75,'LineWidth',2,'Color',app.gcolor)
            text(axes,xintercept+sgnx,0.5,'1','Color',app.gcolor)
            % add normal and text showing plane where values are 0
            quiver(axes,xintercept,0,-sgnx,0,0,'MaxHeadSize',.75,'LineWidth',2,'Color',app.rcolor)
            text(axes,xintercept-sgnx,0.5,'0','Color',app.rcolor)
            
            axes.DataAspectRatio = [1 1 1];
        end
        
        
        function drawGeometricInterpretation2D(app,axes,bias,w1,w2,marker,style)
            xintercept = -bias/w1; 
            yintercept = -bias/w2;
            if isnan(yintercept) || isinf(yintercept)
                drawGeometricInterpretation1D(app,axes,bias,w1);
            elseif isnan(xintercept) || isinf(xintercept)
                % add y-axis
                if yintercept>=0
                    quiver(axes,0,-app.radius,0,yintercept+app.radius*2,0,'MaxHeadSize',.15,'Color','k')
                else
                    quiver(axes,0,yintercept-app.radius,0,-yintercept+app.radius*2,0,'MaxHeadSize',.15,'Color','k')
                end
                % add x-axis
                quiver(axes,-app.radius*1.5,0,app.radius*3,0,0,'MaxHeadSize',.15,'Color','k')
                % add separation line
                line(axes,[-app.radius,app.radius], [yintercept yintercept], 'LineWidth',2,'Color',app.bcolor)
                % add normal and text showing plane where values are 1
                sgn = sign(app.WeightsTable.Data.Values(2));sgn(sgn==0)=1;
                quiver(axes,0,yintercept,0,sgn,0,'MaxHeadSize',.75,'LineWidth',2,'Color',app.gcolor)
                text(axes,0.5,yintercept+sgn,'1','Color',app.gcolor)
                % add normal and text showing plane where values are 0
                quiver(axes,0,yintercept,0,-sgn,0,'MaxHeadSize',.75,'LineWidth',2,'Color',app.rcolor)
                text(axes,yintercept-sgn,0.5,'0','Color',app.rcolor)
            else
                % add x-axis 
                if xintercept>=0 && marker == 'o'
                    quiver(axes,-app.radius,0,xintercept+app.radius*2,0,0,'MaxHeadSize',.15,'Color','k')
                else
                    quiver(axes,xintercept-app.radius,0,-xintercept+app.radius*2,0,'MaxHeadSize',.15,'Color','k')
                end
                % add y-axis
                if yintercept>=0 && marker == 'o'
                    quiver(axes,0,-app.radius,0,yintercept+app.radius*2,0,'MaxHeadSize',.15,'Color','k')
                else
                    quiver(axes,0,yintercept-app.radius,0,-yintercept+app.radius*2,0,'MaxHeadSize',.15,'Color','k')
                end
                % add normal and text showing plane where values are 1
                m = w2/w1; % normal slope
                xc = xintercept/2; yc = yintercept/2;
                %             b = yc-m*xc; % normal x intercept
                dx = 1/sqrt(1+m^2);
                dy = m*dx;
                sgn = sign(w1);sgn(sgn==0)=1;
                % add separation line
                line(axes,[xintercept 0], [0 yintercept],'LineWidth',2,'Color',app.bcolor,'LineStyle',style)
                if xintercept == 0
                    plot(axes,-1:1,[1,0,-1]/m+yintercept,'LineWidth',2,'Color',app.bcolor,'LineStyle',style)
                end
                quiver(axes,xc,yc,sgn*dx,sgn*dy,0,'MaxHeadSize',.75,'LineWidth',2,'Color',app.gcolor,'LineStyle',style)
                text(axes,xc+sgn*dx+sgn*.25,yc+sgn*dy+sgn*.25,'1','Color',app.gcolor)
                % add normal and text showing plane where values are 0
                quiver(axes,xc,yc,-sgn*dx,-sgn*dy,0,'MaxHeadSize',.75,'LineWidth',2,'Color',app.rcolor,'LineStyle',style)
                text(axes,xc-sgn*dx-sgn*.25,yc-sgn*dy-sgn*.25,'0','Color',app.rcolor)
            end
            if isempty(app.y)
                generateInputVector(app,str2double(app.InputsDropDown.Value));
            end
            app.y = app.step(app.x*[w1;w2;bias],0);
            idx = find(app.y==1);
            plot(axes,app.x(idx,1),app.x(idx,2),marker,'Color',app.gcolor,'LineWidth',2);
            idx = find(app.y==0);
            plot(axes,app.x(idx,1),app.x(idx,2),marker,'Color',app.rcolor,'LineWidth',2);
            axes.DataAspectRatio = [1 1 1];
        end
        
        function plotInput(app,axes,weight,level)
            % line xweight to product
            quiver(axes,-app.radius*4,level,app.radius-.5,0,0,'MaxHeadSize',.5,'Color',app.bcolor)
            % text xweight
            sgn = sign(level);
            sgn(sgn==0)=1;
            text(axes,-app.radius*4,level+sgn*1,['x_' num2str(weight)])
            % product and text weight
            app.plotProduct(axes,3,0,level)
            app.textw{weight} = text(axes,-app.radius*3-.5,level+sgn*1,['w_' num2str(weight) '(' num2str(app.WeightsTable.Data.Values(weight)) ')']); % text w
        end
        
        function plotProduct(app,axes,factor,xdisplacement,ydisplacement)
            % product circle
            plot(axes,-app.radius*factor-0.5*app.circlex+xdisplacement,0.5*app.circley+ydisplacement,'LineWidth',2,'Color',app.gcolor)
            % cross lines
            line(axes,xdisplacement+[-app.radius*factor-0.5*cos(3*pi/4) -app.radius*factor-0.5*cos(7*pi/4)],ydisplacement+[0.5*sin(3*pi/4) 0.5*sin(7*pi/4)],'LineWidth',2,'Color',app.gcolor) % product w1
            line(axes,xdisplacement+[-app.radius*factor-0.5*cos(pi/4) -app.radius*factor-0.5*cos(5*pi/4)],ydisplacement+[0.5*sin(pi/4) 0.5*sin(5*pi/4)],'LineWidth',2,'Color',app.gcolor)
        end
        
        function plotSum(app,axes)
            % plot sum circle
            plot(axes,-app.radius*2-.5*app.circlex,.5*app.circley,'LineWidth',2,'Color',app.gcolor) % sum circle
            line(axes,[-app.radius*2 -app.radius*2],[-.5 .5],'LineWidth',2,'Color',app.gcolor) % sum vertical line
            line(axes,[-app.radius*2-.5 -app.radius*2+.5],[0 0],'LineWidth',2,'Color',app.gcolor) % sum horizontal line
            % plot sum input bias and text
            quiver(axes,-app.radius*2,app.radius,0,-app.radius+.5,0,'MaxHeadSize',.5,'Color',app.bcolor)
            app.textbias = text(axes,-app.radius*2-.5,app.radius+.5,['bias(' num2str(app.BiasEditField.Value) ')']); % text bias
            % plot line sum to func
            quiver(axes,-app.radius*2+.5,0,app.radius-.5,0,0,'MaxHeadSize',.5,'Color',app.bcolor)
        end
        
        function plotWeightToSum(app,axes,level)
            quiver(axes,-app.radius*3+.5,level,app.radius-1,-level,0,'MaxHeadSize',.5,'Color',app.bcolor) % line w1 to sum
        end
        
        function f = step(~,x,bias)
            f = 0;
            f(x>=bias) = 1;
        end
        
        function updateSingleLayerOnlineLearningTab(app)
            noinputs = str2double(app.InputsDropDown.Value);
            app.generateInputVector(noinputs);
            w = [app.WeightsTable.Data.Values;app.BiasEditField.Value];
            app.y = double(app.x*w>=0);
            tx = table(app.x(:,1:end-1),app.y);
            app.TrainingDataTable.Data = tx;
            tt = table(0,NaN(1,noinputs),NaN,NaN,NaN,NaN,w(1:end-1)',w(end),'VariableNames',app.OnlineLearningTable.ColumnName);
            app.OnlineLearningTable.Data = tt;
        end
        
        function generateInputVector(app,n)
            % generate input vector (last column is 1 for the bias)
            app.x = zeros(2^n,n+1); app.x(:,1:end) = deal([dec2bin(0:2^n-1),repmat('1',2^n,1)]-double('0'));
        end
        
        
        function w = onlineTraining(app)
            
            % initialize weights (last weight is bias)
            w = [app.WeightsTable.Data.Values',app.BiasEditField.Value];
            n = size(app.x,1);
            alpha = app.LearningRateEditField.Value;
            
            td = app.OnlineLearningTable.Data;
            td(2:end,:) = [];
            err = 1; epoch = 1;
            while err>0 && epoch<100
                % initalize accumulated error
                err = 0;
                td.Epoch(end+1:end+n,1) = epoch;
                td.x(end-n+1:end,:)=app.x(:,1:end-1);
                td.y(end-n+1:end)=app.y;
                % for every set of inputs
                for i = 1:n
                    % compute the actual output
                    td.wx(end-n+i) = dot(w,app.x(i,:));
                    td.o(end-n+i) = 1;
                    if td.wx(end-n+i)<0
                        td.o(end-n+i) = 0;
                    end
                    % compute error
                    td.e(end-n+i) = app.y(i)-td.o(end-n+i);
                    % update weights if the actual output does not equal the expected
                    % output (if abs(e)>0)
                    w = w + alpha*td.e(end-n+i)*app.x(i,:);
                    td.w(end-n+i,:) = w(1:end-1);
                    td.w0(end-n+i) = w(end);
                    % accumulate error
                    err = err + abs(td.e(end-n+i));
                end
                epoch = epoch+1;
            end
            app.OnlineLearningTable.Data = td;
            app.WeightsTable.Data.Values = w(1:end-1)';
            app.BiasEditField.Value=w(end);
        
            sgn = '-';
            if w(end)>=0
                sgn = '+';
            end
            app.textbias.String = ['bias(' sgn num2str(abs(w(end))) ')'];
        
            app.textw{1}.String = ['w_1(' num2str(w(1)) ')'];
            switch size(app.x,2)-1
                case 1
                    app.texty.String = ['=step(' num2str(w(1)) '*x_1' sgn num2str(abs(w(end))) ')'];
                case 2
                    app.textw{2}.String = ['w_2(' num2str(w(2)) ')'];
                    app.texty.String = ['=step(' num2str(w(1)) '*x_1+' num2str(w(2)) '*x_2' sgn num2str(abs(w(end))) ')'];
                end                
            end
        end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off','all')
            
            app.rcolor = [217 95 2]/255;
            app.gcolor = [127 201 127]/255;
            app.bcolor = [56 108 176]/255;
            
            app.theta = linspace(0,2*pi,50);% Description
            app.radius = 2;
            app.circlex = cos(app.theta); % Description
            app.circley = sin(app.theta); % Description
            
            weights = {'w1'};
            values = 1;
            app.WeightsTable.Data = table(weights,values,'VariableNames',app.WeightsTable.ColumnName);
            app.drawNeuron(app.NeuronArchitectureAxes,1);
        end

        % Value changed function: InputsDropDown
        function InputsDropDownValueChanged(app, event)
          value = str2double(app.InputsDropDown.Value);
          switch(value)
              case 1
                  weights = {'w1'};
                  values = 1;
                  app.WeightsTable.Data = table(weights,values,'VariableNames',app.WeightsTable.ColumnName);
              case 2
                  weights = {'w1';'w2'};
                  values = [1;1];
                  app.WeightsTable.Data = table(weights,values,'VariableNames',app.WeightsTable.ColumnName);
          end
          app.x = []; app.y = [];
          app.drawNeuron(app.NeuronArchitectureAxes,1);
        end

        % Value changed function: BiasEditField
        function BiasEditFieldValueChanged(app, event)
            value = app.BiasEditField.Value;
            sgn = '-';
            if value>=0
                sgn = '+';
            end
            value = num2str(abs(value));
            app.textbias.String = ['bias(' sgn value ')'];
            k = find(app.texty.String=='x',1,'last');
            app.texty.String(k+3:end)=[];
            app.texty.String=[app.texty.String sgn value ')'];
            cla(app.GeometricInterpretationAxes);
            app.GeometricInterpretationAxes.NextPlot = 'add';
            switch (str2double(app.InputsDropDown.Value))
                case 1
                    app.drawGeometricInterpretation1D(app.GeometricInterpretationAxes,app.BiasEditField.Value,app.WeightsTable.Data.Values(1));
                case 2
                    app.drawGeometricInterpretation2D(app.GeometricInterpretationAxes,app.BiasEditField.Value,app.WeightsTable.Data.Values(1),app.WeightsTable.Data.Values(2),'o','-');
                    %                 otherwise
            end
        end

        % Cell edit callback: WeightsTable
        function WeightsTableCellEdit(app, event)
            indices = event.Indices;
            newData = num2str(event.NewData);
            app.textw{indices(1)}.String = ['w_' num2str(indices(1)) '(' newData ')'];
            k2 = strfind(app.texty.String,['*x_' num2str(indices(1))]);
            k1 = find(app.texty.String(1:k2-1)=='+',1,'last');
            k1(isempty(k1))=find(app.texty.String(1:k2-1)=='(');
            app.texty.String = [app.texty.String(1:k1) newData app.texty.String(k2:end)];
            cla(app.GeometricInterpretationAxes);
            app.GeometricInterpretationAxes.NextPlot = 'add';
            switch (str2double(app.InputsDropDown.Value))
                case 1
                    app.drawGeometricInterpretation1D(app.GeometricInterpretationAxes,app.BiasEditField.Value,app.WeightsTable.Data.Values(1));
                case 2
                    app.drawGeometricInterpretation2D(app.GeometricInterpretationAxes,app.BiasEditField.Value,app.WeightsTable.Data.Values(1),app.WeightsTable.Data.Values(2),'o','-');
                    %                 otherwise
            end
        end

        % Callback function
        function InputsDropDown2ValueChanged(app, event)
          
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.SingleLayerDesignerTab
                app.drawNeuron(app.NeuronArchitectureAxes,1);
            elseif selectedTab == app.SingleLayerOnlineLearningTab
                app.drawNeuron(app.NeuronArchitectureAxes2,0);
                app.updateSingleLayerOnlineLearningTab;
            else
                app.generateInputVector(2);
                app.drawMultilayer();
                params = {'w11';'w21';'w01';'w12';'w22';'w02'};
                values = [1;1;0;1;1;0];
                app.ParametersTable.Data = table(params,values,'VariableNames',app.ParametersTable.ColumnName);
                cla(app.Layer1InterpretationAxes);
                app.Layer1InterpretationAxes.NextPlot = 'add';
                drawGeometricInterpretation2D(app,app.Layer1InterpretationAxes,0,1,1,'o','-');
            end
        end

        % Button pushed function: TrainNeuronButton
        function TrainNeuronButtonPushed(app, event)
            app.onlineTraining();            
        end

        % Cell edit callback: TrainingDataTable
        function TrainingDataTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.y(indices(1))=newData;
        end

        % Cell edit callback: ParametersTable
        function ParametersTableCellEdit(app, event)
            cla(app.Layer1InterpretationAxes);
            app.Layer1InterpretationAxes.NextPlot = 'add';
            app.drawGeometricInterpretation2D(app.Layer1InterpretationAxes,app.ParametersTable.Data.Values(3),app.ParametersTable.Data.Values(1),app.ParametersTable.Data.Values(2),'o','-');
            app.drawGeometricInterpretation2D(app.Layer1InterpretationAxes,app.ParametersTable.Data.Values(6),app.ParametersTable.Data.Values(4),app.ParametersTable.Data.Values(5),'x',':');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SingleLayerDesignerTab = uitab(app.TabGroup);
            app.SingleLayerDesignerTab.Title = 'SingleLayerDesigner';
            app.InputsDropDownLabel = uilabel(app.SingleLayerDesignerTab);
            app.InputsDropDownLabel.Text = 'Inputs';
            app.InputsDropDown = uidropdown(app.SingleLayerDesignerTab);
            app.InputsDropDown.Items = {'1', '2', ''};
            app.InputsDropDown.ValueChangedFcn = createCallbackFcn(app, @InputsDropDownValueChanged, true);
            app.InputsDropDown.Value = '1';
            app.BiasEditFieldLabel = uilabel(app.SingleLayerDesignerTab);
            app.BiasEditFieldLabel.Text = 'Bias';
            app.BiasEditField = uieditfield(app.SingleLayerDesignerTab, 'numeric');
            app.BiasEditField.ValueChangedFcn = createCallbackFcn(app, @BiasEditFieldValueChanged, true);
            app.WeightsTable = uitable(app.SingleLayerDesignerTab);
            app.WeightsTable.ColumnName = {'Weights'; 'Values'};
            app.WeightsTable.RowName = {};
            app.WeightsTable.ColumnEditable = [false true];
            app.WeightsTable.CellEditCallback = createCallbackFcn(app, @WeightsTableCellEdit, true);
            app.NeuronArchitectureAxes = uiaxes(app.SingleLayerDesignerTab);
            title(app.NeuronArchitectureAxes, 'Neuron Architecture')
            app.NeuronArchitectureAxes.XTick = [];
            app.NeuronArchitectureAxes.YTick = [];
            app.GeometricInterpretationAxes = uiaxes(app.SingleLayerDesignerTab);
            title(app.GeometricInterpretationAxes, 'Geometric Interpretation')
            app.GeometricInterpretationAxes.XTick = [];
            app.GeometricInterpretationAxes.YTick = [];
            app.SingleLayerOnlineLearningTab = uitab(app.TabGroup);
            app.SingleLayerOnlineLearningTab.Title = 'SingleLayerOnlineLearning';
            app.TrainingDataTable = uitable(app.SingleLayerOnlineLearningTab);
            app.TrainingDataTable.ColumnName = {'x'; 'y'};
            app.TrainingDataTable.RowName = {};
            app.TrainingDataTable.ColumnEditable = [false true];
            app.TrainingDataTable.CellEditCallback = createCallbackFcn(app, @TrainingDataTableCellEdit, true);
            app.OnlineLearningTable = uitable(app.SingleLayerOnlineLearningTab);
            app.OnlineLearningTable.ColumnName = {'Epoch'; 'x'; 'wx'; 'o'; 'y'; 'e'; 'w'; 'w0'};
            app.OnlineLearningTable.RowName = {};
            app.TrainNeuronButton = uibutton(app.SingleLayerOnlineLearningTab, 'push');
            app.TrainNeuronButton.ButtonPushedFcn = createCallbackFcn(app, @TrainNeuronButtonPushed, true);
            app.TrainNeuronButton.Text = 'TrainNeuron';
            app.LearningRateEditFieldLabel = uilabel(app.SingleLayerOnlineLearningTab);
            app.LearningRateEditFieldLabel.Text = {'Learning'; 'Rate'};
            app.LearningRateEditField = uieditfield(app.SingleLayerOnlineLearningTab, 'numeric');
            app.LearningRateEditField.Value = 1;
            app.NeuronArchitectureAxes2 = uiaxes(app.SingleLayerOnlineLearningTab);
            title(app.NeuronArchitectureAxes2, 'Neuron Architecture')
            app.NeuronArchitectureAxes2.XTick = [];
            app.NeuronArchitectureAxes2.YTick = [];
            app.MultilayerDesignerTab = uitab(app.TabGroup);
            app.MultilayerDesignerTab.Title = 'MultilayerDesigner';
            app.ParametersTable = uitable(app.MultilayerDesignerTab);
            app.ParametersTable.ColumnName = {'Weights'; 'Values'};
            app.ParametersTable.RowName = {};
            app.ParametersTable.ColumnEditable = [false true];
            app.ParametersTable.CellEditCallback = createCallbackFcn(app, @ParametersTableCellEdit, true);
            app.MultilayerArchitectureAxes = uiaxes(app.MultilayerDesignerTab);
            title(app.MultilayerArchitectureAxes, 'Multilayer Architecture')
            app.MultilayerArchitectureAxes.XTick = [];
            app.MultilayerArchitectureAxes.YTick = [];
            app.Layer1InterpretationAxes = uiaxes(app.MultilayerDesignerTab);
            title(app.Layer1InterpretationAxes, 'Interpretation Layer 1')
            app.Layer1InterpretationAxes.XTick = [];
            app.Layer1InterpretationAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Perceptron

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end